classdef final_cut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StartButton        matlab.ui.control.Button
        COMEditField       matlab.ui.control.EditField
        COMEditFieldLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        %Pax = polaraxes(app.UIFigure);
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)

            value = app.COMEditField.Value;
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            step = 7;
            port = app.COMEditField.Value;
device = serialport(port, 9600); % 定義設備
data = zeros(step, 2, 'double'); % 初始化數據矩陣以存儲角度和值
data(:,1) = (pi/4).*(0:step-1);


for i = 1:step
    dataLine = fscanf(device, '%f'); % 讀取一行數據，期望為一個整數和一個浮點數
    data(i,2) = dataLine'; % 儲存數據
    
end


validRows = data(:,2) <= 160 & data(:,2) >= 40;
filteredData = data(validRows, :);


delete(device); % 刪除定義的設備
clear device; % 清除設備變量

% 現在 data 矩陣中應該有完整的數據，其中 data(:,1) 是角度，data(:,2) 是對應的值

% 畫圖

% tosan daata
if filteredData(1,1) < 0.1
    filteredData(1,:) = [];
end
filteredData = fliplr(filteredData')';
if filteredData(1,1) > 4.5
    filteredData(1,:) = [];
end
filteredData = fliplr(filteredData')';


% polarplot(data(:,1),data(:,2),'bo', app.pax)

polar(app.UIAxes,filteredData(:,1),filteredData(:,2),'bo');
% 
% app.dataLabel.Text = data;


filteredData(:,1) = filteredData(:,1)*(180/pi)

fig = uifigure;
uit = uitable(fig, "Data", filteredData);


% fig = uifigure('Name', '極座標');
% pax = polaraxes(fig);
% pax.Position = [100 100 300 300];
% polarplot(pax, data);
% title(pax, '極座標');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           % app.pax = polaraxes(app.UIAxes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_cut

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end